classdef Design1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        Alpha5                  matlab.ui.control.NumericEditField
        Alpha5Label             matlab.ui.control.Label
        Theta5                  matlab.ui.control.NumericEditField
        Theta5Label             matlab.ui.control.Label
        L5                      matlab.ui.control.NumericEditField
        L5Label                 matlab.ui.control.Label
        Workspace               matlab.ui.control.DropDown
        WorkspaceDropDownLabel  matlab.ui.control.Label
        Alpha4                  matlab.ui.control.NumericEditField
        Alpha4Label             matlab.ui.control.Label
        Theta4                  matlab.ui.control.NumericEditField
        Theta4Label             matlab.ui.control.Label
        L4                      matlab.ui.control.NumericEditField
        L4Label                 matlab.ui.control.Label
        EvaluateButton          matlab.ui.control.Button
        NumOfLinks              matlab.ui.control.Spinner
        NumOfLinksSpinnerLabel  matlab.ui.control.Label
        Z_2                     matlab.ui.control.NumericEditField
        ZLabel                  matlab.ui.control.Label
        Y_2                     matlab.ui.control.NumericEditField
        YLabel                  matlab.ui.control.Label
        X_2                     matlab.ui.control.NumericEditField
        XLabel                  matlab.ui.control.Label
        DHParamterLabel         matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        SumbitButton            matlab.ui.control.Button
        Mode                    matlab.ui.control.DropDown
        ModeDropDownLabel       matlab.ui.control.Label
        PeterCorkeLabel         matlab.ui.control.Label
        X                       matlab.ui.control.NumericEditField
        XEditFieldLabel         matlab.ui.control.Label
        Z                       matlab.ui.control.NumericEditField
        ZEditFieldLabel         matlab.ui.control.Label
        Y                       matlab.ui.control.NumericEditField
        YEditFieldLabel         matlab.ui.control.Label
        Theta3                  matlab.ui.control.NumericEditField
        Theta3EditFieldLabel    matlab.ui.control.Label
        Alpha3                  matlab.ui.control.NumericEditField
        Alpha3EditFieldLabel    matlab.ui.control.Label
        Alpha2                  matlab.ui.control.NumericEditField
        Alpha2EditFieldLabel    matlab.ui.control.Label
        Alpha1                  matlab.ui.control.NumericEditField
        Alpha1EditFieldLabel    matlab.ui.control.Label
        Theta2                  matlab.ui.control.NumericEditField
        Theta2EditFieldLabel    matlab.ui.control.Label
        L3                      matlab.ui.control.NumericEditField
        L3EditFieldLabel        matlab.ui.control.Label
        Theta1                  matlab.ui.control.NumericEditField
        Theta1EditFieldLabel    matlab.ui.control.Label
        L2                      matlab.ui.control.NumericEditField
        L2EditFieldLabel        matlab.ui.control.Label
        L1                      matlab.ui.control.NumericEditField
        L1EditFieldLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SumbitButton
        function SumbitButtonPushed(app, event)

            Th_1 = app.Theta1.Value*(pi/180);
            Th_2 = app.Theta2.Value*(pi/180);
            Th_3 = app.Theta3.Value*(pi/180);
            Th_4 = app.Theta4.Value*(pi/180);
            Th_5 = app.Theta5.Value*(pi/180);
            L_1 = app.L1.Value;
            L_2 = app.L2.Value;
            L_3 = app.L3.Value;
            L_4 = app.L4.Value;
            L_5 = app.L5.Value;
            alpha1 = app.Alpha1.Value*(pi/180);
            alpha2 = app.Alpha2.Value*(pi/180);
            alpha3 = app.Alpha3.Value*(pi/180);
            alpha4 = app.Alpha4.Value*(pi/180);
            alpha5 = app.Alpha5.Value*(pi/180);
            X_ = app.X_2.Value;
            Y_ = app.Y_2.Value ;
            syms alph d th a
            A = [cos(th) -sin(th)*cos(alph) sin(th)*sin(alph) a*cos(th)
                sin(th) cos(th)*cos(alph) -cos(th)*sin(alph) a*sin(th)
                0 sin(alph) cos(alph) d
                0 0 0 1];
            
           syms th1 th2 th3 th4 th5
      
           A1 = subs(A,{a,alph,d,th},{0, pi/2 ,app.L1.Value,Th_1});
           A2 = subs(A,{a,alph,d,th},{app.L2.Value, alpha2 ,0,Th_2});
           A3 = subs(A,{a,alph,d,th},{app.L3.Value,alpha3 ,0,Th_3});
           A4 = subs(A,{a,alph,d,th},{0,alpha4 ,L_4 ,Th_4});
           A5 = subs(A,{a,alph,d,th},{L_5,alpha5,0,Th_5});
           if(app.NumOfLinks.Value == 3)
               At = simplify(A1*A2*A3);
               L(1) = Link([Th_1 L_1 0 pi/2 ]);
               L(2) = Link([Th_2 0 L_2 alpha2 ]);
               L(3) = Link([Th_3 0 L_3 alpha3]);
               Robot = SerialLink(L);
               Robot.name = 'Robot';
               Robot.plot([Th_1 Th_2 Th_3]);
               T = Robot.fkine([Th_1 Th_2 Th_3]);
               Matrix = T.t;
               app.X.Value = double(Matrix(1,1));
               app.Y.Value = double(Matrix(2,1));
               app.Z.Value = double(Matrix(3,1));
               app.X_2.Value = double(At(1,4));
               app.Y_2.Value = double(At(2,4));
               app.Z_2.Value = double(At(3,4));
           elseif(app.NumOfLinks.Value == 2)
               At = simplify(A1*A2);
               L(1) = Link([Th_1 L_1 0 pi/2 ]);
               L(2) = Link([Th_2 0 L_2 alpha2 ]);
               Robot = SerialLink(L);
               Robot.name = 'Robot';
               Robot.plot([Th_1 Th_2 ]);
               T = Robot.fkine([Th_1 Th_2]);
               Matrix = T.t;
               app.X.Value = double(Matrix(1,1));
               app.Y.Value = double(Matrix(2,1));
               app.Z.Value = double(Matrix(3,1));
               app.X_2.Value = double(At(1,4));
               app.Y_2.Value = double(At(2,4));
               app.Z_2.Value = double(At(3,4));
           elseif(app.NumOfLinks.Value == 4)
               L(1) = Link([Th_1 L_1 0 pi/2 ]);
               L(2) = Link([Th_2 0 L_2 alpha2 ]);
               L(3) = Link([Th_3 0 L_3 alpha3]);
               L(4) = Link([Th_4 L_4 0 alpha4]);
               Robot = SerialLink(L);
               Robot.name = 'Robot';
               At = simplify(A1*A2*A3*A4); 
               Robot.plot([Th_1 Th_2 Th_3 Th_4]);
               T = Robot.fkine([Th_1 Th_2 Th_3 Th_4]);
               Matrix = T.t;
               app.X.Value = double(Matrix(1,1));
               app.Y.Value = double(Matrix(2,1));
               app.Z.Value = double(Matrix(3,1));
               app.X_2.Value = double(At(1,4));
               app.Y_2.Value = double(At(2,4));
               app.Z_2.Value = double(At(3,4));
               
           elseif(app.NumOfLinks.Value == 5)
               L(1) = Link([Th_1 L_1 0 pi/2 ]);
               L(2) = Link([Th_2 0 L_2 alpha2 ]);
               L(3) = Link([Th_3 0 L_3 alpha3]);
               L(4) = Link([Th_4 L_4 0 alpha4]);
               L(5) = Link([Th_5 0 L_5 alpha5]);
               
               Robot = SerialLink(L);
               Robot.name = 'Robot';
               At = simplify(A1*A2*A3*A4*A5); 
               Robot.plot([Th_1 Th_2 Th_3 Th_4 Th_5]);
               T = Robot.fkine([Th_1 Th_2 Th_3 Th_4 Th_5]);
               Matrix = T.t;
               app.X.Value = double(Matrix(1,1));
               app.Y.Value = double(Matrix(2,1));
               app.Z.Value = double(Matrix(3,1));
               app.X_2.Value = double(At(1,4));
               app.Y_2.Value = double(At(2,4));
               app.Z_2.Value = double(At(3,4));
               
               
           end
           if (strcmp(app.Mode.Value,'Inverse'))
                L_1 = app.L1.Value;
                L_2 = app.L2.Value;    
        
                x = app.X_2.Value;
                y = app.Y_2.Value;
                
                XE = x;
                YE = y;
%Gemotric Sol                
                if((L_1+L_2)<sqrt((x*x)+(y*y)))
                    error('Endd-Effector unreachable');

                elseif((L_1+L_2)>sqrt((x*x)+(y*y)))

                end
                
                th1i = 2*atan((2*L_1*YE + (- L_1^4 + 2*L_1^2*L_2^2 + 2*L_1^2*XE^2 + 2*L_1^2*YE^2 - L_2^4 + 2*L_2^2*XE^2 + 2*L_2^2*YE^2 - XE^4 - 2*XE^2*YE^2 - YE^4)^(1/2))/(L_1^2 + 2*L_1*XE - L_2^2 + XE^2 + YE^2));
                th2i =-2*atan(((- L_1^2 + 2*L_1*L_2 - L_2^2 + XE^2 + YE^2)*(L_1^2 + 2*L_1*L_2 + L_2^2 - XE^2 - YE^2))^(1/2)/(- L_1^2 + 2*L_1*L_2 - L_2^2 + XE^2 + YE^2));
                app.Theta1.Value= double(th1i*(180/pi));
                app.Theta2.Value= double(th2i*(180/pi));


%PeterCorke By Jac                 
                % Create the robot model
                if((L_1+L_2)<sqrt((x*x)+(y*y)))
                
                    error('Endd-Effector unreachable');
                end
                o(1) = Link([app.Theta1.Value 0 L_1 0]);
                o(2) = Link([app.Theta2.Value 0 L_2 0]);
                kr = SerialLink(o);
                qf = [app.Theta1.Value app.Theta2.Value];
                tf = [1 0 0 x;
                      0 1 0 y;
                      0 0 1 0;
                      0 0 0 1;];
                q0 = [0 0];
                q = kr.ikine(tf,q0,'mask',[1 1 0 0 0 0])
                app.Theta3.Value=q(1,1)*(180/pi);
                app.Theta4.Value=q(1,2)*(180/pi);
                
                end


        end

        % Value changed function: NumOfLinks
        function NumOfLinksValueChanged(app, event)
            value = app.NumOfLinks.Value;
            if (app.NumOfLinks.Value == 2)
                   app.L1.Enable= true;
                   app.L2.Enable= true;
                   app.L3.Enable = false;
                   app.L4.Enable = false;
                   app.L5.Enable = false;
                   app.Theta1.Enable = true;
                   app.Theta2.Enable = true;
                   app.Theta3.Enable = false;
                   app.Theta4.Enable = false;
                   app.Theta5.Enable = false;
                    
                   app.X.Enable = false;
                   app.Y.Enable = false;
                   app.Z.Enable = false;
                   app.X_2.Enable = false;
                   app.Y_2.Enable = false;
                   app.Z_2.Enable = false;
                    
                   app.Alpha1.Enable = true;
                   app.Alpha2.Enable = true;
                   app.Alpha3.Enable = false;
                   app.Alpha4.Enable = false;
                   app.Alpha5.Enable = false;
            elseif(app.NumOfLinks.Value == 3)
                   app.L1.Enable= true;
                   app.L2.Enable= true;
                   app.L3.Enable = true;
                   app.L4.Enable = false;
                   app.L5.Enable = false;
                   app.Theta1.Enable = true;
                   app.Theta2.Enable = true;
                   app.Theta3.Enable = true;
                   app.Theta4.Enable = false;
                   app.Theta5.Enable = false;
                    
                   app.X.Enable = false;
                   app.Y.Enable = false;
                   app.Z.Enable = false;
                   app.X_2.Enable = false;
                   app.Y_2.Enable = false;
                   app.Z_2.Enable = false;
                    
                   app.Alpha1.Enable = true;
                   app.Alpha2.Enable = true;
                   app.Alpha3.Enable = true;
                   app.Alpha4.Enable = false;
                   app.Alpha5.Enable = false;
            elseif(app.NumOfLinks.Value == 4)
                   app.L1.Enable= true;
                   app.L2.Enable= true;
                   app.L3.Enable = true;
                   app.L4.Enable = true;
                   app.L5.Enable = false;
                   app.Theta1.Enable = true;
                   app.Theta2.Enable = true;
                   app.Theta3.Enable = true;
                   app.Theta4.Enable = true;
                   app.Theta5.Enable = false;
                    
                   app.X.Enable = false;
                   app.Y.Enable = false;
                   app.Z.Enable = false;
                   app.X_2.Enable = false;
                   app.Y_2.Enable = false;
                   app.Z_2.Enable = false;
                    
                   app.Alpha1.Enable = true;
                   app.Alpha2.Enable = true;
                   app.Alpha3.Enable = true;
                   app.Alpha4.Enable = true;
                   app.Alpha5.Enable = false;
            elseif(app.NumOfLinks.Value == 5)
                   app.L1.Enable= true;
                   app.L2.Enable= true;
                   app.L3.Enable = true;
                   app.L4.Enable = true;
                   app.L5.Enable = true;
                   app.Theta1.Enable = true;
                   app.Theta2.Enable = true;
                   app.Theta3.Enable = true;
                   app.Theta4.Enable = true;
                   app.Theta5.Enable = true;
                    
                   app.X.Enable = false;
                   app.Y.Enable = false;
                   app.Z.Enable = false;
                   app.X_2.Enable = false;
                   app.Y_2.Enable = false;
                   app.Z_2.Enable = false;
                    
                   app.Alpha1.Enable = true;
                   app.Alpha2.Enable = true;
                   app.Alpha3.Enable = true;
                   app.Alpha4.Enable = true;
                   app.Alpha5.Enable = true;
            end
        end

        % Value changed function: Mode
        function ModeValueChanged(app, event)
            value = app.Mode.Value;
            if(strcmp(app.Mode.Value,'Forward'))
                app.Theta1.Enable = true;
                app.Theta2.Enable = true;
                app.Theta3.Enable = false;
                app.Theta4.Enable = false;
                app.Theta5.Enable = false;
                
                app.X.Enable = false;
                app.Y.Enable = false;
                app.Z.Enable = false;
                app.X_2.Enable = false;
                app.Y_2.Enable = false;
                app.Z_2.Enable = false;
                
                app.Alpha1.Enable = true;
                app.Alpha2.Enable = true;
                app.Alpha3.Enable = false;
                app.Alpha4.Enable = false;
                app.Alpha5.Enable = false;
                app.L1.Enable = true;
                app.L2.Enable = true;
                app.L3.Enable = false;
                app.L4.Enable = false;
                app.L5.Enable = false;
                app.EvaluateButton.Enable=false
                app.SumbitButton.Enable = true
                app.NumOfLinks.Enable=true
                
            elseif (strcmp(app.Mode.Value,'Inverse'))
                   app.L1.Enable= true;
                   app.L2.Enable= true;
                   app.L3.Enable = false;
                   app.L4.Enable = false;
                   app.L5.Enable = false;
                   app.Theta1.Enable = false;
                   app.Theta2.Enable = false;
                   app.Theta3.Enable = false;
                   app.Theta4.Enable = false;
                   app.Theta5.Enable = false;
                    
                   app.X.Enable = false;
                   app.Y.Enable = false;
                   app.Z.Enable = false;
                   app.X_2.Enable = true;
                   app.Y_2.Enable = true;
                   app.Z_2.Enable = false;
                    
                   app.Alpha1.Enable = false;
                   app.Alpha2.Enable = false;
                   app.Alpha3.Enable = false;
                   app.Alpha4.Enable = false;
                   app.Alpha5.Enable = false;
                   app.EvaluateButton.Enable= false;
                   app.SumbitButton.Enable = true;
                   app.NumOfLinks.Enable=false
                   app.Workspace.Enable=false
            elseif (strcmp(app.Mode.Value,'Workspace'))  
                   app.L1.Enable= true;
                   app.L2.Enable= true;
                   app.L3.Enable = false;
                   app.L4.Enable = false;
                   app.L5.Enable = false;
                   app.Theta1.Enable = true;
                   app.Theta2.Enable = true;
                   app.Theta3.Enable = false;
                   app.Theta4.Enable = false;
                   app.Theta5.Enable = false;
                    
                   app.X.Enable = false;
                   app.Y.Enable = false;
                   app.Z.Enable = false;
                   app.X_2.Enable = false;
                   app.Y_2.Enable = false;
                   app.Z_2.Enable = false;
                    
                   app.Alpha1.Enable = true;
                   app.Alpha2.Enable = true;
                   app.Alpha3.Enable = false;
                   app.Alpha4.Enable = false;
                   app.Alpha5.Enable = false;
                app.Workspace.Enable=true
                app.NumOfLinks.Enable=false
                app.EvaluateButton.Enable=true
                app.SumbitButton.Enable = false


            end
        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            Th_1 = app.Theta1.Value*(pi/180);
            Th_2 = app.Theta2.Value*(pi/180);
            Th_3 = app.Theta3.Value*(pi/180);
            Th_4 = app.Theta4.Value*(pi/180);
            Th_5 = app.Theta5.Value*(pi/180);
            L_1 = app.L1.Value;
            L_2 = app.L2.Value;
            L_3 = app.L3.Value;
            L_4 = app.L4.Value;
            L_5 = app.L5.Value;
            alpha1 = app.Alpha1.Value*(pi/180);
            alpha2 = app.Alpha2.Value*(pi/180);
            alpha3 = app.Alpha3.Value*(pi/180);
            alpha4 = app.Alpha4.Value*(pi/180);
            alpha5 = app.Alpha5.Value*(pi/180);
            if(strcmp(app.Workspace.Value,'2D'))
                DH(1) = Link([Th_1 L_1 0 pi/2 ]);
                DH(2) = Link([Th_2 0 L_2 alpha2 ]);
                th1 = (-pi:0.5:pi) ;
                th2 = (-pi:0.6:pi);
                q = {th1,th2};
                r = SerialLink(DH);
                r.display()
                r.teach()
                hold on 
                [~,n] = size(DH);
                var = sym('q',[n 1]);
                assume(var,'real')
                
                [Q{1:numel(q)}] = ndgrid(q{:}); 
                T = simplify(vpa(r.fkine(var),3));
                Pos = T.tv;
                x(var(:)) = Pos(1);
                X = matlabFunction(x);
                X = X(Q{:});
                y(var(:)) = Pos(2);
                Y = matlabFunction(y);
                Y = Y(Q{:});
                z(var(:)) = Pos(3);
                Z = matlabFunction(z);
                Z = Z(Q{:});
                plot3(X(:),Y(:),Z(:),'r.')
                xlabel('X')
                ylabel('Y')
                zlabel('Z')
                
            elseif(strcmp(app.Workspace.Value,'4D'))
                DH(1) = Link([Th_1 L_1 0 pi/2 ]);
                DH(2) = Link([Th_2 0 L_2 alpha2 ]);
                DH(3) = Link([Th_3 0 L_3 alpha3]);
                DH(4) = Link([Th_4 L_4 0 alpha4]);
                th1 = (-pi:0.5:pi) ;
                th2 = (-pi/2:0.4:pi/2);
                th3 = (-pi/2:0.4:pi/2) ;
                th4 = (-pi:0.4:pi) ;
                q = {th1,th2,th3,th4};
                r = SerialLink(DH);
                r.display()
                r.teach()
                hold on 
                [~,n] = size(DH);
                var = sym('q',[n 1]);
                assume(var,'real')
                % generate a grid of theta1 and theta2,3,4 values
                [Q{1:numel(q)}] = ndgrid(q{:}); 
                T = simplify(vpa(r.fkine(var),3));
                Pos = T.tv;

                x(var(:)) = Pos(1);
                X = matlabFunction(x);
                X = X(Q{:});
                y(var(:)) = Pos(2);
                Y = matlabFunction(y);
                Y = Y(Q{:});
                z(var(:)) = Pos(3);
                Z = matlabFunction(z);
                Z = Z(Q{:});

                xlabel('X')
                ylabel('Y')
                zlabel('Z')
                size_X = size(X);
                size_Y = size(Y);
                size_Z = size(Z);
                
                if ~isequal(size_X, size_Y, size_Z)
                    error('Sizes of X, Y, and Z vectors are not equal.');
                end
                % Plot only if sizes are equal
                plot3(X(:), Y(:), Z(:), 'r.')
                xlabel('X')
                ylabel('Y')
                zlabel('Z')

            elseif(strcmp(app.Workspace.Value,'3D'))
                DH(1) = Link([Th_1 L_1 0 pi/2 ]);
                DH(2) = Link([Th_2 0 L_2 alpha2 ]);
                DH(3) = Link([Th_3 0 L_3 alpha3]);
                th1 = (-pi:0.5:pi) ;
                th2 = (-pi/2:0.6:pi/2);
                th3 = (-pi/2:0.6:pi/2) ;
                q = {th1,th2,th3};
                r = SerialLink(DH);
                r.display()
                r.teach()
                hold on 
                [~,n] = size(DH);
                var = sym('q',[n 1]);
                assume(var,'real')
                % generate a grid of theta1 and theta2,3,4 values
                [Q{1:numel(q)}] = ndgrid(q{:}); 
                T = simplify(vpa(r.fkine(var),3));
                Pos = T.tv;
                x(var(:)) = Pos(1);
                X = matlabFunction(x);
                X = X(Q{:});
                y(var(:)) = Pos(2);
                Y = matlabFunction(y);
                Y = Y(Q{:});
                z(var(:)) = Pos(3);
                Z = matlabFunction(z);
                Z = Z(Q{:});
                plot3(X(:),Y(:),Z(:),'r.')
                xlabel('X')
                ylabel('Y')
                zlabel('Z')
                
            elseif(strcmp(app.Workspace.Value,'5D'))  
                DH(1) = Link([Th_1 L_1 0 pi/2 ]);
                DH(2) = Link([Th_2 0 L_2 alpha2 ]);
                DH(3) = Link([Th_3 0 L_3 alpha3]);
                DH(4) = Link([Th_4 L_4 0 alpha4]);
                DH(5) = Link([Th_5 0 L_5 alpha5]);
                th1 = (-pi:0.8:pi) ;
                th2 = (-pi/2:0.8:pi/2);
                th3 = (-pi/2:0.9:pi/2) ;
                th4 = (-pi/2:0.9:pi/2) ;
                th5 = (-pi/2:0.8:pi/2) ;
                q = {th1,th2,th3,th4,th5};
                r = SerialLink(DH);
                r.display()
                r.teach()
                hold on 
                [~,n] = size(DH);
                var = sym('q',[n 1]);
                assume(var,'real')
                % generate a grid of theta1 and theta2,3,4,5 values
                [Q{1:numel(q)}] = ndgrid(q{:}); 
                T = simplify(vpa(r.fkine(var),3));
                Pos = T.tv;
                x(var(:)) = Pos(1);
                X = matlabFunction(x);
                X = X(Q{:});
                y(var(:)) = Pos(2);
                Y = matlabFunction(y);
                Y = Y(Q{:});
                z(var(:)) = Pos(3);
                Z = matlabFunction(z);
                Z = Z(Q{:});
                plot3(X(:),Y(:),Z(:),'r.')
                xlabel('X')
                ylabel('Y')
                zlabel('Z')
            end
        end

        % Value changed function: Workspace
        function WorkspaceValueChanged(app, event)
            value = app.Workspace.Value;
             if (strcmp(app.Workspace.Value,'2D'))
                   app.L1.Enable= true;
                   app.L2.Enable= true;
                   app.L3.Enable = false;
                   app.L4.Enable = false;
                   app.L5.Enable = false;
                   app.Theta1.Enable = true;
                   app.Theta2.Enable = true;
                   app.Theta3.Enable = false;
                   app.Theta4.Enable = false;
                   app.Theta5.Enable = false;
                    
                   app.X.Enable = false;
                   app.Y.Enable = false;
                   app.Z.Enable = false;
                   app.X_2.Enable = false;
                   app.Y_2.Enable = false;
                   app.Z_2.Enable = false;
                    
                   app.Alpha1.Enable = true;
                   app.Alpha2.Enable = true;
                   app.Alpha3.Enable = false;
                   app.Alpha4.Enable = false;
                   app.Alpha5.Enable = false;
            elseif(strcmp(app.Workspace.Value,'3D'))
                   app.L1.Enable= true;
                   app.L2.Enable= true;
                   app.L3.Enable = true;
                   app.L4.Enable = false;
                   app.L5.Enable = false;
                   app.Theta1.Enable = true;
                   app.Theta2.Enable = true;
                   app.Theta3.Enable = true;
                   app.Theta4.Enable = false;
                   app.Theta5.Enable = false;
                    
                   app.X.Enable = false;
                   app.Y.Enable = false;
                   app.Z.Enable = false;
                   app.X_2.Enable = false;
                   app.Y_2.Enable = false;
                   app.Z_2.Enable = false;
                    
                   app.Alpha1.Enable = true;
                   app.Alpha2.Enable = true;
                   app.Alpha3.Enable = true;
                   app.Alpha4.Enable = false;
                   app.Alpha5.Enable = false;
            elseif(strcmp(app.Workspace.Value,'4D'))
                   app.L1.Enable= true;
                   app.L2.Enable= true;
                   app.L3.Enable = true;
                   app.L4.Enable = true;
                   app.L5.Enable = false;
                   app.Theta1.Enable = true;
                   app.Theta2.Enable = true;
                   app.Theta3.Enable = true;
                   app.Theta4.Enable = true;
                   app.Theta5.Enable = false;
                    
                   app.X.Enable = false;
                   app.Y.Enable = false;
                   app.Z.Enable = false;
                   app.X_2.Enable = false;
                   app.Y_2.Enable = false;
                   app.Z_2.Enable = false;
                    
                   app.Alpha1.Enable = true;
                   app.Alpha2.Enable = true;
                   app.Alpha3.Enable = true;
                   app.Alpha4.Enable = true;
                   app.Alpha5.Enable = false;
            elseif(strcmp(app.Workspace.Value,'5D'))
                   app.L1.Enable= true;
                   app.L2.Enable= true;
                   app.L3.Enable = true;
                   app.L4.Enable = true;
                   app.L5.Enable = true;
                   app.Theta1.Enable = true;
                   app.Theta2.Enable = true;
                   app.Theta3.Enable = true;
                   app.Theta4.Enable = true;
                   app.Theta5.Enable = true;
                    
                   app.X.Enable = false;
                   app.Y.Enable = false;
                   app.Z.Enable = false;
                   app.X_2.Enable = false;
                   app.Y_2.Enable = false;
                   app.Z_2.Enable = false;
                    
                   app.Alpha1.Enable = true;
                   app.Alpha2.Enable = true;
                   app.Alpha3.Enable = true;
                   app.Alpha4.Enable = true;
                   app.Alpha5.Enable = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.L1EditFieldLabel = uilabel(app.UIFigure);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1 = uieditfield(app.UIFigure, 'numeric');
            app.L1.Value = 10;
            app.L2EditFieldLabel = uilabel(app.UIFigure);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2 = uieditfield(app.UIFigure, 'numeric');
            app.Theta1EditFieldLabel = uilabel(app.UIFigure);
            app.Theta1EditFieldLabel.Text = 'Theta1';
            app.Theta1 = uieditfield(app.UIFigure, 'numeric');
            app.Theta1.RoundFractionalValues = 'on';
            app.L3EditFieldLabel = uilabel(app.UIFigure);
            app.L3EditFieldLabel.Text = 'L3';
            app.L3 = uieditfield(app.UIFigure, 'numeric');
            app.L3.Enable = 'off';
            app.Theta2EditFieldLabel = uilabel(app.UIFigure);
            app.Theta2EditFieldLabel.Text = 'Theta2';
            app.Theta2 = uieditfield(app.UIFigure, 'numeric');
            app.Theta2.RoundFractionalValues = 'on';
            app.Alpha1EditFieldLabel = uilabel(app.UIFigure);
            app.Alpha1EditFieldLabel.Text = 'Alpha1';
            app.Alpha1 = uieditfield(app.UIFigure, 'numeric');
            app.Alpha2EditFieldLabel = uilabel(app.UIFigure);
            app.Alpha2EditFieldLabel.Text = 'Alpha2';
            app.Alpha2 = uieditfield(app.UIFigure, 'numeric');
            app.Alpha3EditFieldLabel = uilabel(app.UIFigure);
            app.Alpha3EditFieldLabel.Text = 'Alpha3';
            app.Alpha3 = uieditfield(app.UIFigure, 'numeric');
            app.Alpha3.Enable = 'off';
            app.Theta3EditFieldLabel = uilabel(app.UIFigure);
            app.Theta3EditFieldLabel.Text = 'Theta3';
            app.Theta3 = uieditfield(app.UIFigure, 'numeric');
            app.Theta3.RoundFractionalValues = 'on';
            app.Theta3.Enable = 'off';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.Y = uieditfield(app.UIFigure, 'numeric');
            app.Y.RoundFractionalValues = 'on';
            app.Y.ValueDisplayFormat = '%11.485340g';
            app.Y.Enable = 'off';
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.Z = uieditfield(app.UIFigure, 'numeric');
            app.Z.Enable = 'off';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.X = uieditfield(app.UIFigure, 'numeric');
            app.X.Enable = 'off';
            app.PeterCorkeLabel = uilabel(app.UIFigure);
            app.PeterCorkeLabel.Text = 'Peter Corke';
            app.ModeDropDownLabel = uilabel(app.UIFigure);
            app.ModeDropDownLabel.Text = 'Mode';
            app.Mode = uidropdown(app.UIFigure);
            app.Mode.Items = {'Forward', 'Inverse', 'Workspace'};
            app.Mode.ValueChangedFcn = createCallbackFcn(app, @ModeValueChanged, true);
            app.Mode.Value = 'Forward';
            app.SumbitButton = uibutton(app.UIFigure, 'push');
            app.SumbitButton.ButtonPushedFcn = createCallbackFcn(app, @SumbitButtonPushed, true);
            app.SumbitButton.Text = 'Sumbit';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'|'; '|'; '|'; '|'; '|'; '|'};
            app.DHParamterLabel = uilabel(app.UIFigure);
            app.DHParamterLabel.Text = 'DH Paramter';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X-';
            app.X_2 = uieditfield(app.UIFigure, 'numeric');
            app.X_2.Enable = 'off';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y-';
            app.Y_2 = uieditfield(app.UIFigure, 'numeric');
            app.Y_2.Enable = 'off';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z-';
            app.Z_2 = uieditfield(app.UIFigure, 'numeric');
            app.Z_2.Enable = 'off';
            app.NumOfLinksSpinnerLabel = uilabel(app.UIFigure);
            app.NumOfLinksSpinnerLabel.Text = 'NumOfLinks';
            app.NumOfLinks = uispinner(app.UIFigure);
            app.NumOfLinks.Limits = [2 5];
            app.NumOfLinks.ValueChangedFcn = createCallbackFcn(app, @NumOfLinksValueChanged, true);
            app.NumOfLinks.Value = 2;
            app.EvaluateButton = uibutton(app.UIFigure, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Enable = 'off';
            app.EvaluateButton.Text = 'Evaluate';
            app.L4Label = uilabel(app.UIFigure);
            app.L4Label.Text = 'L4';
            app.L4 = uieditfield(app.UIFigure, 'numeric');
            app.L4.Enable = 'off';
            app.Theta4Label = uilabel(app.UIFigure);
            app.Theta4Label.Text = 'Theta4';
            app.Theta4 = uieditfield(app.UIFigure, 'numeric');
            app.Theta4.RoundFractionalValues = 'on';
            app.Theta4.Enable = 'off';
            app.Alpha4Label = uilabel(app.UIFigure);
            app.Alpha4Label.Text = 'Alpha4';
            app.Alpha4 = uieditfield(app.UIFigure, 'numeric');
            app.Alpha4.Enable = 'off';
            app.WorkspaceDropDownLabel = uilabel(app.UIFigure);
            app.WorkspaceDropDownLabel.Text = 'Workspace';
            app.Workspace = uidropdown(app.UIFigure);
            app.Workspace.Items = {'2D', '3D', '4D', '5D'};
            app.Workspace.ValueChangedFcn = createCallbackFcn(app, @WorkspaceValueChanged, true);
            app.Workspace.Enable = 'off';
            app.Workspace.Value = '2D';
            app.L5Label = uilabel(app.UIFigure);
            app.L5Label.Text = 'L5';
            app.L5 = uieditfield(app.UIFigure, 'numeric');
            app.L5.Enable = 'off';
            app.Theta5Label = uilabel(app.UIFigure);
            app.Theta5Label.Text = 'Theta5';
            app.Theta5 = uieditfield(app.UIFigure, 'numeric');
            app.Theta5.RoundFractionalValues = 'on';
            app.Theta5.Enable = 'off';
            app.Alpha5Label = uilabel(app.UIFigure);
            app.Alpha5Label.Text = 'Alpha5';
            app.Alpha5 = uieditfield(app.UIFigure, 'numeric');
            app.Alpha5.Enable = 'off';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Robot_.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Design1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end